classdef Convolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        differenceequationLabel   matlab.ui.control.Label
        inputLabel                matlab.ui.control.Label
        EquationynLabel           matlab.ui.control.Label
        CustomEditField           matlab.ui.control.EditField
        CustomEditFieldLabel      matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        lengthEditField           matlab.ui.control.NumericEditField
        lengthEditFieldLabel      matlab.ui.control.Label
        presetDropDown            matlab.ui.control.DropDown
        presetDropDownLabel       matlab.ui.control.Label
        x_coefficientEditField_2  matlab.ui.control.EditField
        bEditLabel                matlab.ui.control.Label
        y_coefficientEditField    matlab.ui.control.EditField
        aEditLabel                matlab.ui.control.Label
        SimulateButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function arr = parseArray(app, str)
            % '1, 0, -0.5' -> [1 0 -0.5]
            if isnumeric(str)
                arr = double(str(:)).';
                return
            end
            s = char(str);
            s = strtrim(s);
            assert(~isempty(s), 'Empty input.');
            parts = regexp(s, '[,;\s]+', 'split');
            arr = str2double(parts);
            if any(isnan(arr))
                error('Invalid number in input: %s', s);
            end
            arr = arr(:).';
        end
        
        function validateCoeffs(app, a,b)
            assert(~isempty(a) && ~isempty(b), 'a,b must be non-empty.');
            assert(abs(a(1))>0, 'a(1) must be non-zero.');
        end
        
        function out = joinArray(app, x)
            out = strjoin(compose('%g', x), ', ');
        end
        
        function setTextIfPossible(app, txt)
            try
                app.CustomEditField.Value = txt;
            catch
                
            end
        end

        
        function updateEquationLabel(app)
            try
                a = app.parseArray(app.y_coefficientEditField.Value);
                b = app.parseArray(app.x_coefficientEditField_2.Value);
                assert(~isempty(a) && abs(a(1))>0, 'a(1) must be non-zero.');
                a0 = a(1);
        
                fmt = '%.4g';
                mk = @(c) regexprep(sprintf(fmt,c),'^\+?0$','0');
        
                yTerms = strings(0);
                for k = 1:numel(a)-1
                    c = a(k+1)/a0;
                    if abs(c) < 1e-12, continue; end
                    if k == 1
                        idx = 'n-1';
                    else
                        idx = sprintf('n-%d', k);
                    end
                    yTerms(end+1) = sprintf('%s·y[%s]', mk(c), idx); %#ok<AGROW>
                end
        
                xTerms = strings(0);
                for m = 0:numel(b)-1
                    c = b(m+1)/a0;
                    if abs(c) < 1e-12, continue; end
                    if m == 0
                        idx = 'n';
                    else
                        idx = sprintf('n-%d', m);
                    end
                    xTerms(end+1) = sprintf('%s·x[%s]', mk(c), idx); %#ok<AGROW>
                end
        
                rhs = strjoin([yTerms, xTerms], ' = ');
                rhs = regexprep(rhs, '\+\s-\s', '- '); 
        
                eqnText = sprintf('Equation:  y[n] + %s', rhs);
        
                app.EquationynLabel.Interpreter = 'none';
                app.EquationynLabel.WordWrap = 'on';
                app.EquationynLabel.Text = eqnText;
        
            catch
                app.EquationynLabel.Interpreter = 'none';
                app.EquationynLabel.WordWrap = 'on';
                app.EquationynLabel.Text = 'Equation:  (invalid a/b)';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.y_coefficientEditField.Value = '1, -0.5';
            app.x_coefficientEditField_2.Value = '1';
        
            app.lengthEditField.Value = 20;
            app.presetDropDown.Value = 'impulse';
            presetDropDownValueChanged(app, []);
            app.CustomEditField.Enable   = 'off';
            % clear
            cla(app.UIAxes);  cla(app.UIAxes2);  cla(app.UIAxes3);
            title(app.UIAxes,'Input x[n]');  xlabel(app.UIAxes,'n');  ylabel(app.UIAxes,'x[n]'); grid(app.UIAxes,'on');
            title(app.UIAxes2,'Output y[n]'); xlabel(app.UIAxes2,'n'); ylabel(app.UIAxes2,'y[n]'); grid(app.UIAxes2,'on');
            title(app.UIAxes3,'unit-sample response'); xlabel(app.UIAxes3,'n'); ylabel(app.UIAxes3,'h[n]'); grid(app.UIAxes3,'on');
            updateEquationLabel(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try
                cla(app.UIAxes); cla(app.UIAxes2);
                if isprop(app.CustomEditField, 'Value')
                    app.CustomEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                a = app.parseArray(app.y_coefficientEditField.Value);
                b = app.parseArray(app.x_coefficientEditField_2.Value);
                app.validateCoeffs(a, b);
        
                if strcmp(app.presetDropDown.Value, 'Custom')
                    x = app.parseArray(app.CustomEditField.Value);
                else
                    N = max(1, round(app.lengthEditField.Value));
                    switch app.presetDropDown.Value
                        case 'impulse',     x = [1, zeros(1, N-1)];
                        case 'step',        x = ones(1, N);
                        case 'sine(0.2pi)', x = sin(0.2*pi*(0:N-1));
                        otherwise,          x = ones(1, N);
                    end
                end
        
                % compute
                N = max(1, round(app.lengthEditField.Value));
                h = filter(b, a, [1, zeros(1, N-1)]);
                %y = filter(b, a, x);
                y_full = conv(x, h, 'full');
                y = y_full(1:N);
                n = 0:numel(x)-1;
        
                cla(app.UIAxes);  cla(app.UIAxes2); cla(app.UIAxes3);
                stem(app.UIAxes, n, x, 'filled');  grid(app.UIAxes, 'on');
                title(app.UIAxes, 'Input x[n]');   xlabel(app.UIAxes, 'n'); ylabel(app.UIAxes, 'x[n]');

                stem(app.UIAxes3, n, h, 'filled');  grid(app.UIAxes3, 'on');
                title(app.UIAxes3, 'unit-sample response');   xlabel(app.UIAxes3, 'n'); ylabel(app.UIAxes3, 'h[n]');
        
                stem(app.UIAxes2, n, y, 'filled'); grid(app.UIAxes2, 'on');
                title(app.UIAxes2, 'Output y[n]'); xlabel(app.UIAxes2, 'n'); ylabel(app.UIAxes2, 'y[n]');

                updateEquationLabel(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Error', 'Icon','error');
            end
        end

        % Value changed function: presetDropDown
        function presetDropDownValueChanged(app, event)
            N = max(1, round(app.lengthEditField.Value));
            sel = app.presetDropDown.Value;
        
            switch sel
                case 'Custom'
                    % input by user
                    app.CustomEditField.Enable   = 'on';
                    app.lengthEditField.Enable   = 'off';
                case 'impulse'
                    txt = ['1', repmat(', 0', 1, N-1)];
                    setTextIfPossible(app, txt);
                    app.CustomEditField.Enable   = 'off';
                    app.lengthEditField.Enable   = 'on';
                case 'step'
                    txt = strjoin(repmat({'1'}, 1, N), ', ');
                    setTextIfPossible(app, txt);
                    app.CustomEditField.Enable   = 'off';
                    app.lengthEditField.Enable   = 'on';
                case 'sine(0.2pi)'
                    n = 0:N-1;
                    x = sin(0.2*pi*n);
                    txt = app.joinArray(x);
                    setTextIfPossible(app, txt);
                    app.CustomEditField.Enable   = 'off';
                    app.lengthEditField.Enable   = 'on';
            end
        end

        % Value changed function: y_coefficientEditField
        function y_coefficientEditFieldValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Value changed function: x_coefficientEditField_2
        function x_coefficientEditField_2ValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Value changed function: CustomEditField
        function CustomEditFieldValueChanged(app, event)
            N = [];
            try
                x = app.parseArray(app.CustomEditField.Value);
                N = max(0, numel(x));
            catch
            end
            if ~isempty(N)
                app.lengthEditField.Value = N;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {644, 644};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.aEditLabel = uilabel(app.LeftPanel);
            app.aEditLabel.Text = 'y_coefficient';
            app.y_coefficientEditField = uieditfield(app.LeftPanel, 'text');
            app.y_coefficientEditField.ValueChangedFcn = createCallbackFcn(app, @y_coefficientEditFieldValueChanged, true);
            app.y_coefficientEditField.Value = '1';
            app.bEditLabel = uilabel(app.LeftPanel);
            app.bEditLabel.Text = 'x_coefficient';
            app.x_coefficientEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.x_coefficientEditField_2.ValueChangedFcn = createCallbackFcn(app, @x_coefficientEditField_2ValueChanged, true);
            app.x_coefficientEditField_2.Value = '1';
            app.presetDropDownLabel = uilabel(app.LeftPanel);
            app.presetDropDownLabel.Text = 'preset';
            app.presetDropDown = uidropdown(app.LeftPanel);
            app.presetDropDown.Items = {'Custom', 'impulse', 'step', 'sine(0.2pi)'};
            app.presetDropDown.ValueChangedFcn = createCallbackFcn(app, @presetDropDownValueChanged, true);
            app.presetDropDown.Value = 'Custom';
            app.lengthEditFieldLabel = uilabel(app.LeftPanel);
            app.lengthEditFieldLabel.Text = 'length';
            app.lengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.lengthEditField.Value = 50;
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CustomEditFieldLabel = uilabel(app.LeftPanel);
            app.CustomEditFieldLabel.Text = 'Custom';
            app.CustomEditField = uieditfield(app.LeftPanel, 'text');
            app.CustomEditField.ValueChangedFcn = createCallbackFcn(app, @CustomEditFieldValueChanged, true);
            app.EquationynLabel = uilabel(app.LeftPanel);
            app.EquationynLabel.Text = 'Equation: y[n] = ';
            app.inputLabel = uilabel(app.LeftPanel);
            app.inputLabel.Text = 'input';
            app.differenceequationLabel = uilabel(app.LeftPanel);
            app.differenceequationLabel.Text = 'difference equation';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'input')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'x[n]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'output')
            xlabel(app.UIAxes2, 'n')
            ylabel(app.UIAxes2, 'y[n]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'unit-sample response')
            xlabel(app.UIAxes3, 'n')
            ylabel(app.UIAxes3, 'h[n]')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Convolution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end