classdef freq_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        BandrangeSlider           matlab.ui.control.RangeSlider
        BandrangeSliderLabel      matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        MEditField_2              matlab.ui.control.EditField
        MLabel                    matlab.ui.control.Label
        xncosnLabel               matlab.ui.control.Label
        lengthEditField_2         matlab.ui.control.NumericEditField
        lengthEditField_2Label    matlab.ui.control.Label
        inputxLabel               matlab.ui.control.Label
        EquationynLabel           matlab.ui.control.Label
        systemLabel               matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        SimulateButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes3_2                 matlab.ui.control.UIAxes
        UIAxes2_2                 matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        orderEditField            matlab.ui.control.NumericEditField
        orderEditFieldLabel       matlab.ui.control.Label
        cutofffreqSlider          matlab.ui.control.Slider
        cutofffreqSliderLabel     matlab.ui.control.Label
        y_coefficientEditField    matlab.ui.control.EditField
        aEditLabel                matlab.ui.control.Label
        x_coefficientEditField_2  matlab.ui.control.EditField
        bEditLabel                matlab.ui.control.Label
        filterDropDown            matlab.ui.control.DropDown
        filterDropDownLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function arr = parseArray(app, str)
            % '1, 0, -0.5' -> [1 0 -0.5]
            if isnumeric(str)
                arr = double(str(:)).';
                return
            end
            s = char(str);
            s = strtrim(s);
            assert(~isempty(s), 'Empty input.');
            parts = regexp(s, '[,;\s]+', 'split');
            arr = str2double(parts);
            if any(isnan(arr))
                error('Invalid number in input: %s', s);
            end
            arr = arr(:).';
        end
        
        function validateCoeffs(app, a,b)
            assert(~isempty(a) && ~isempty(b), 'a,b must be non-empty.');
            assert(abs(a(1))>0, 'a(1) must be non-zero.');
        end

        
        function updateEquationLabel(app)
            try
                [a, b] = updatesyscoeff(app);
                assert(~isempty(a) && abs(a(1))>0, 'a(1) must be non-zero.');
                a0 = a(1);
        
                fmt = '%.4g';
                mk = @(c) regexprep(sprintf(fmt,c),'^\+?0$','0');
        
                yTerms = strings(0);
                for k = 0:numel(a)-1
                    c = a(k+1)/a0;
                    if abs(c) < 1e-12, continue; end
                    if k == 0
                        idx = 'n';
                    else
                        idx = sprintf('n-%d', k);
                    end
                    yTerms(end+1) = sprintf('%s·y[%s]', mk(c), idx); %#ok<AGROW>
                end
        
                xTerms = strings(0);
                for m = 0:numel(b)-1
                    c = b(m+1)/a0;
                    if abs(c) < 1e-12, continue; end
                    if m == 0
                        idx = 'n';
                    else
                        idx = sprintf('n-%d', m);
                    end
                    xTerms(end+1) = sprintf('%s·x[%s]', mk(c), idx); %#ok<AGROW>
                end
                lhs = strjoin(yTerms, ' + ');
                rhs = strjoin(xTerms, ' + ');
        
                eqnText = sprintf('Equation:  %s = %s', lhs, rhs);
        
                app.EquationynLabel.Interpreter = 'none';
                app.EquationynLabel.WordWrap = 'on';
                app.EquationynLabel.Text = eqnText;
        
            catch
                app.EquationynLabel.Interpreter = 'none';
                app.EquationynLabel.WordWrap = 'on';
                app.EquationynLabel.Text = 'Equation:  (invalid a/b)';
            end
        end

        function x = updateInputX(app)
            try
                M = app.parseArray(app.MEditField_2.Value);
                omega = app.parseArray(app.EditField.Value);
                n = 0:app.lengthEditField_2.Value-1;
                assert(~isempty(M) && ~isempty(omega), 'must be non-zero.');
                
                Terms = strings(0);
                for k = 1:numel(omega)
                    Terms(end+1) = sprintf('%.4g·cos[%.4g·πn]', M(k), omega(k)); %#ok<AGROW>
                end

                cosn = strjoin(Terms, ' + ');
        
                eqnText = sprintf('x[n] = %s', cosn);
        
                app.xncosnLabel.Interpreter = 'none';
                app.xncosnLabel.WordWrap = 'on';
                app.xncosnLabel.Text = eqnText;
                
                M = M(:); omega = omega(:); n = n(:).';

                x = sum(M .* cos(omega*pi .* n), 1);
        
            catch
                app.xncosnLabel.Interpreter = 'none';
                app.xncosnLabel.WordWrap = 'on';
                app.xncosnLabel.Text = 'x[n]:  (invalid)';
            end
        end

        function [a, b] =updatesyscoeff(app)
            try
                N = app.orderEditField.Value;
                switch app.filterDropDown.Value
                    case 'enter'
                        a = app.parseArray(app.y_coefficientEditField.Value);
                        b = app.parseArray(app.x_coefficientEditField_2.Value);
                        app.systemLabel.Text = 'system';
                    case 'LPF'
                        Wc = app.cutofffreqSlider.Value; 
                        b  = fir1(N, Wc); 
                        a  = 1;
                        app.systemLabel.Text = sprintf('systen (< %.3fπ) ', Wc);
                    case 'HPF'
                        Wc = app.cutofffreqSlider.Value;
                        b  = fir1(N, Wc, 'high');
                        a  = 1;
                        app.systemLabel.Text = sprintf('systen (> %.3fπ) ', Wc);
                    case 'BPF'
                        W  = app.BandrangeSlider.Value;
                        b  = fir1(N, W);
                        a  = 1;
                        app.systemLabel.Text = sprintf('systen (%.3fπ ~ %.3fπ) ', W(1), W(2));
                    %otherwise ,a=1; b=1;
                end
            catch ME
                a=1;b=1;
                app.systemLabel.Text = ['updatesyscoeff err: ' ME.message];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.y_coefficientEditField.Value = '1';
            app.x_coefficientEditField_2.Value = '0.33, 0.33, 0.33';
        
            app.MEditField_2.Value = '1, 0.5';
            app.EditField.Value = '0.2, 0.6';
            updateInputX(app);

            app.filterDropDown.Value = 'enter';
            filterDropDownValueChanged(app, []);
            
            % clear
            cla(app.UIAxes);  cla(app.UIAxes2);  cla(app.UIAxes3); cla(app.UIAxes_2);  cla(app.UIAxes2_2);  cla(app.UIAxes3_2);
            title(app.UIAxes,'Input x[n]');  xlabel(app.UIAxes,'n');  ylabel(app.UIAxes,'x[n]'); grid(app.UIAxes,'on');
            title(app.UIAxes2,'Output y[n]'); xlabel(app.UIAxes2,'n'); ylabel(app.UIAxes2,'y[n]'); grid(app.UIAxes2,'on');
            title(app.UIAxes3,'system h[n]'); xlabel(app.UIAxes3,'n'); ylabel(app.UIAxes3,'h[n]'); grid(app.UIAxes3,'on');
            title(app.UIAxes_2,'Input x[n]');  xlabel(app.UIAxes_2,'Ω');  ylabel(app.UIAxes_2,'x[n]'); grid(app.UIAxes_2,'on');
            title(app.UIAxes2_2,'Output y[n]'); xlabel(app.UIAxes2_2,'Ω'); ylabel(app.UIAxes2_2,'y[n]'); grid(app.UIAxes2_2,'on');
            title(app.UIAxes3_2,'system h[n]'); xlabel(app.UIAxes3_2,'Ω'); ylabel(app.UIAxes3_2,'h[n]'); grid(app.UIAxes3_2,'on');
            updateEquationLabel(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try
                cla(app.UIAxes); cla(app.UIAxes2);
                if isprop(app.CutoffFrequencyEditField, 'Value')
                    app.CutoffFrequencyEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                [a, b] = updatesyscoeff(app);
                app.validateCoeffs(a, b);
                
                x = updateInputX(app);
                N = app.lengthEditField_2.Value;
                h = filter(b, a, [1, zeros(1, N-1)]);
                N = N/2;
                y = filter(b, a, x);
                n = 0:numel(x)-1;
                f = linspace(-pi, pi, numel(x));
                cla(app.UIAxes);  cla(app.UIAxes2); cla(app.UIAxes3); cla(app.UIAxes_2);  cla(app.UIAxes2_2); cla(app.UIAxes3_2);
                stem(app.UIAxes, n, x, 'filled');  grid(app.UIAxes, 'on');
                title(app.UIAxes, 'Input x[n]');   xlabel(app.UIAxes, 'n'); ylabel(app.UIAxes, 'x[n]');

                stem(app.UIAxes3, n, h, 'filled');  grid(app.UIAxes3, 'on');
                title(app.UIAxes3, 'system h[n]');   xlabel(app.UIAxes3, 'n'); ylabel(app.UIAxes3, 'h[n]');
        
                stem(app.UIAxes2, n, y, 'filled'); grid(app.UIAxes2, 'on');
                title(app.UIAxes2, 'Output y[n]'); xlabel(app.UIAxes2, 'n'); ylabel(app.UIAxes2, 'y[n]');

                stem(app.UIAxes_2, f, abs(fftshift(fft(x)))/N, 'filled');  grid(app.UIAxes_2, 'on');
                title(app.UIAxes_2, 'Input x[n]');   xlabel(app.UIAxes_2, 'Ω'); ylabel(app.UIAxes_2, 'x[n]');

                stem(app.UIAxes3_2, f, abs(fftshift(fft(h)))/N, 'filled');  grid(app.UIAxes3_2, 'on');
                title(app.UIAxes3_2, 'system h[n]');   xlabel(app.UIAxes3_2, 'Ω'); ylabel(app.UIAxes3_2, 'h[n]');
        
                stem(app.UIAxes2_2, f, abs(fftshift(fft(y)))/N, 'filled'); grid(app.UIAxes2_2, 'on');
                title(app.UIAxes2_2, 'Output y[n]'); xlabel(app.UIAxes2_2, 'Ω'); ylabel(app.UIAxes2_2, 'y[n]');

                updateEquationLabel(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Error', 'Icon','error');
            end
        end

        % Value changed function: filterDropDown
        function filterDropDownValueChanged(app, event)
            sel = app.filterDropDown.Value;
            switch sel
                case 'enter'
                    % input by user
                    app.cutofffreqSlider.Visible   = 'off';
                    app.BandrangeSlider.Visible   = 'off';
                    app.cutofffreqSliderLabel.Visible   = 'off';
                    app.BandrangeSliderLabel.Visible   = 'off';
                    app.y_coefficientEditField.Enable = 'on';
                    app.x_coefficientEditField_2.Enable = 'on';
                    app.orderEditField.Visible   = 'off';
                    app.orderEditFieldLabel.Visible   = 'off';
                case 'LPF'
                    app.cutofffreqSlider.Visible   = 'on';
                    app.BandrangeSlider.Visible   = 'off';
                    app.cutofffreqSliderLabel.Visible   = 'on';
                    app.BandrangeSliderLabel.Visible   = 'off';
                    app.y_coefficientEditField.Enable = 'off';
                    app.x_coefficientEditField_2.Enable = 'off';
                    app.orderEditField.Visible   = 'on';
                    app.orderEditFieldLabel.Visible = 'on';
                case 'HPF'
                    app.cutofffreqSlider.Visible   = 'on';
                    app.BandrangeSlider.Visible   = 'off';
                    app.cutofffreqSliderLabel.Visible   = 'on';
                    app.BandrangeSliderLabel.Visible   = 'off';
                    app.y_coefficientEditField.Enable = 'off';
                    app.x_coefficientEditField_2.Enable = 'off';
                    app.orderEditField.Visible   = 'on';
                    app.orderEditFieldLabel.Visible = 'on';
                case 'BPF'
                    app.cutofffreqSlider.Visible   = 'off';
                    app.BandrangeSlider.Visible   = 'on';
                    app.cutofffreqSliderLabel.Visible   = 'off';
                    app.BandrangeSliderLabel.Visible   = 'on';
                    app.y_coefficientEditField.Enable = 'off';
                    app.x_coefficientEditField_2.Enable = 'off';
                    app.orderEditField.Visible   = 'on';
                    app.orderEditFieldLabel.Visible = 'on';
            end
            updateEquationLabel(app);
        end

        % Value changed function: y_coefficientEditField
        function y_coefficientEditFieldValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Value changed function: x_coefficientEditField_2
        function x_coefficientEditField_2ValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Value changed function: MEditField_2
        function MEditField_2ValueChanged(app, event)
            updateInputX(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            updateInputX(app);
        end

        % Value changed function: orderEditField
        function orderEditFieldValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Value changed function: cutofffreqSlider
        function cutofffreqSliderValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Value changed function: BandrangeSlider
        function BandrangeSliderValueChanged(app, event)
            updateEquationLabel(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {716, 716};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {277, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.systemLabel = uilabel(app.LeftPanel);
            app.systemLabel.Text = 'system';
            app.EquationynLabel = uilabel(app.LeftPanel);
            app.EquationynLabel.Text = 'Equation: y[n] = ';
            app.inputxLabel = uilabel(app.LeftPanel);
            app.inputxLabel.Text = 'input x';
            app.lengthEditField_2Label = uilabel(app.LeftPanel);
            app.lengthEditField_2Label.Text = 'length';
            app.lengthEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.lengthEditField_2.Value = 20;
            app.xncosnLabel = uilabel(app.LeftPanel);
            app.xncosnLabel.Text = 'x[n] = cos[πn]';
            app.MLabel = uilabel(app.LeftPanel);
            app.MLabel.Text = 'M';
            app.MEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.MEditField_2.ValueChangedFcn = createCallbackFcn(app, @MEditField_2ValueChanged, true);
            app.MEditField_2.Value = '1';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Ω';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '1';
            app.BandrangeSliderLabel = uilabel(app.LeftPanel);
            app.BandrangeSliderLabel.Text = 'Band range';
            app.BandrangeSlider = uislider(app.LeftPanel, 'range');
            app.BandrangeSlider.Limits = [0.1 0.9];
            app.BandrangeSlider.ValueChangedFcn = createCallbackFcn(app, @BandrangeSliderValueChanged, true);
            app.BandrangeSlider.Step = 0.01;
            app.BandrangeSlider.Value = [0.2 0.5];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'input')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'x[n]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'output')
            xlabel(app.UIAxes2, 'n')
            ylabel(app.UIAxes2, 'y[n]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'unit-sample response')
            xlabel(app.UIAxes3, 'n')
            ylabel(app.UIAxes3, 'h[n]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'input')
            xlabel(app.UIAxes_2, 'n')
            ylabel(app.UIAxes_2, 'x[n]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'output')
            xlabel(app.UIAxes2_2, 'n')
            ylabel(app.UIAxes2_2, 'y[n]')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.RightPanel);
            title(app.UIAxes3_2, 'unit-sample response')
            xlabel(app.UIAxes3_2, 'n')
            ylabel(app.UIAxes3_2, 'h[n]')
            zlabel(app.UIAxes3_2, 'Z')
            app.filterDropDownLabel = uilabel(app.UIFigure);
            app.filterDropDownLabel.Text = 'filter';
            app.filterDropDown = uidropdown(app.UIFigure);
            app.filterDropDown.Items = {'enter', 'LPF', 'HPF', 'BPF'};
            app.filterDropDown.ValueChangedFcn = createCallbackFcn(app, @filterDropDownValueChanged, true);
            app.filterDropDown.Value = 'enter';
            app.bEditLabel = uilabel(app.UIFigure);
            app.bEditLabel.Text = 'x_coefficient';
            app.x_coefficientEditField_2 = uieditfield(app.UIFigure, 'text');
            app.x_coefficientEditField_2.ValueChangedFcn = createCallbackFcn(app, @x_coefficientEditField_2ValueChanged, true);
            app.x_coefficientEditField_2.Value = '1';
            app.aEditLabel = uilabel(app.UIFigure);
            app.aEditLabel.Text = 'y_coefficient';
            app.y_coefficientEditField = uieditfield(app.UIFigure, 'text');
            app.y_coefficientEditField.ValueChangedFcn = createCallbackFcn(app, @y_coefficientEditFieldValueChanged, true);
            app.y_coefficientEditField.Value = '1';
            app.cutofffreqSliderLabel = uilabel(app.UIFigure);
            app.cutofffreqSliderLabel.Text = 'cutoff freq';
            app.cutofffreqSlider = uislider(app.UIFigure);
            app.cutofffreqSlider.Limits = [0.1 0.9];
            app.cutofffreqSlider.ValueChangedFcn = createCallbackFcn(app, @cutofffreqSliderValueChanged, true);
            app.cutofffreqSlider.Step = 0.01;
            app.cutofffreqSlider.Value = 0.5;
            app.orderEditFieldLabel = uilabel(app.UIFigure);
            app.orderEditFieldLabel.Text = 'order';
            app.orderEditField = uieditfield(app.UIFigure, 'numeric');
            app.orderEditField.ValueChangedFcn = createCallbackFcn(app, @orderEditFieldValueChanged, true);
            app.orderEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = freq_filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end